classdef cdose_copy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        EnterLocationofdirectoriestxtfileLabel  matlab.ui.control.Label
        Path1                matlab.ui.control.EditField
        EnterTargetDirectoryEditFieldLabel  matlab.ui.control.Label
        Path2                matlab.ui.control.EditField
        ProgressGaugeLabel   matlab.ui.control.Label
        ProgressGauge        matlab.ui.control.SemicircularGauge
        TextArea             matlab.ui.control.TextArea
        CopyButton           matlab.ui.control.Button
        BrowseButton1        matlab.ui.control.Button
        BrowseButton2        matlab.ui.control.Button
        PrefixoptionalLabel  matlab.ui.control.Label
        Path3                matlab.ui.control.EditField
        YNCheckBox           matlab.ui.control.CheckBox
        BrowseButton3        matlab.ui.control.Button
        ResetButton          matlab.ui.control.Button
        CancelButton         matlab.ui.control.Button
        CumuCheckBox         matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        filepath % list of directories/items to copy
        prefixdir % folder where filepaths are located
        targetdir % path to target directory
        isCancelled = 0
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Path1
        function Path1ValueChanged(app, event)
            app.filepath = app.Path1.Value;
        end

        % Value changed function: Path2
        function Path2ValueChanged(app, event)
            app.targetdir = app.Path2.Value;
        end

        % Button pushed function: CopyButton
        function CopyButtonPushed(app, event)
            fid = fopen(app.filepath);
            t = textscan(fid, '%s', 'Delimiter', '\n');
            t = t{1,1};
            n = numel(t);
            for i=1:n
                if app.isCancelled == 1
                    app.TextArea.Value  = [app.TextArea.Value;'CANCELLED BY USER'];
                    scroll(app.TextArea, 'bottom')
                    break
                end
                if app.YNCheckBox.Value == 1
                    ddir = fullfile(app.prefixdir, t{i});
                else
                    ddir = t{i};
                end
                ddir = strip(ddir, filesep);
                pathparts = strsplit(ddir,filesep);
                destdir = pathparts{end};
                app.TextArea.Value  = [app.TextArea.Value; ['Copying ', ddir, '...']];
                scroll(app.TextArea, 'bottom')
                pause(0.5)
                files = dir(ddir);
                files = files(3:end);
                for f=1:numel(files)
                    if contains(files(f).name, '.raw') && ~contains(files(f).name, '_c.raw') && app.CumuCheckBox.Value == 1
                        continue
                    else
                        if ~exist(fullfile(app.targetdir, destdir), 'dir')
                            mkdir(fullfile(app.targetdir, destdir));
                        end
                        [status,msg] = copyfile(fullfile(ddir, files(f).name), fullfile(app.targetdir, destdir, files(f).name));
                        if status ~= 1
                            disp(['Error/Warning: ', msg]);
                        end
                    end
                end
                app.TextArea.Value  = [app.TextArea.Value; ['Finished copying ', ddir]];
                scroll(app.TextArea, 'bottom')
                app.ProgressGauge.Value = 100*i/n;
                pause(0.5)
            end
            
            if app.isCancelled == 0
                app.TextArea.Value  = [app.TextArea.Value;'COPY COMPLETE'];
                scroll(app.TextArea, 'bottom')
            else
                app.isCancelled = 0;
            end

                
        end

        % Button pushed function: BrowseButton1
        function BrowseButton1Pushed(app, event)
            [file, path] = uigetfile('*.txt');
            figure(app.UIFigure)
            app.Path1.Value  = fullfile(path, file);
            app.filepath = app.Path1.Value;
        end

        % Button pushed function: BrowseButton2
        function BrowseButton2Pushed(app, event)
            app.Path2.Value = uigetdir;
            figure(app.UIFigure)
            app.targetdir = app.Path2.Value;
        end

        % Button pushed function: BrowseButton3
        function BrowseButton3Pushed(app, event)
            app.Path3.Value = uigetdir;
            figure(app.UIFigure)
            app.prefixdir = app.Path3.Value;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.TextArea.Value = '';
            app.Path1.Value = '';
            app.Path2.Value = '';
            app.Path3.Value = '';
            app.ProgressGauge.Value = 0;
            app.YNCheckBox.Value = 0;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            pause(0.5)
            app.TextArea.Value  = [app.TextArea.Value; 'Cancel initiated, waiting to finish copying current item...'];
            app.isCancelled = 1;
            
        end

        % Value changed function: Path3
        function Path3ValueChanged(app, event)
            app.prefixdir = app.Path3.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EnterLocationofdirectoriestxtfileLabel = uilabel(app.UIFigure);
            app.EnterLocationofdirectoriestxtfileLabel.Text = 'Enter Location of directories txt file';
            app.Path1 = uieditfield(app.UIFigure, 'text');
            app.Path1.ValueChangedFcn = createCallbackFcn(app, @Path1ValueChanged, true);
            app.EnterTargetDirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.EnterTargetDirectoryEditFieldLabel.Text = 'Enter Target Directory';
            app.Path2 = uieditfield(app.UIFigure, 'text');
            app.Path2.ValueChangedFcn = createCallbackFcn(app, @Path2ValueChanged, true);
            app.ProgressGaugeLabel = uilabel(app.UIFigure);
            app.ProgressGaugeLabel.Text = 'Progress (%)';
            app.ProgressGauge = uigauge(app.UIFigure, 'semicircular');
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.CopyButton = uibutton(app.UIFigure, 'push');
            app.CopyButton.ButtonPushedFcn = createCallbackFcn(app, @CopyButtonPushed, true);
            app.CopyButton.Text = 'Copy';
            app.BrowseButton1 = uibutton(app.UIFigure, 'push');
            app.BrowseButton1.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton1Pushed, true);
            app.BrowseButton1.Text = 'Browse';
            app.BrowseButton2 = uibutton(app.UIFigure, 'push');
            app.BrowseButton2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton2Pushed, true);
            app.BrowseButton2.Text = 'Browse';
            app.PrefixoptionalLabel = uilabel(app.UIFigure);
            app.PrefixoptionalLabel.Text = 'Prefix (optional)';
            app.Path3 = uieditfield(app.UIFigure, 'text');
            app.Path3.ValueChangedFcn = createCallbackFcn(app, @Path3ValueChanged, true);
            app.YNCheckBox = uicheckbox(app.UIFigure);
            app.YNCheckBox.Text = 'Y/N';
            app.BrowseButton3 = uibutton(app.UIFigure, 'push');
            app.BrowseButton3.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton3Pushed, true);
            app.BrowseButton3.Text = 'Browse';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.CumuCheckBox = uicheckbox(app.UIFigure);
            app.CumuCheckBox.Text = 'Copy Cumulatives Only';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cdose_copy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end